classdef labKitControlStation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KpSliderLabel                 matlab.ui.control.Label
        KpSlider                      matlab.ui.control.Slider
        KpLabel                       matlab.ui.control.Label
        KiSliderLabel                 matlab.ui.control.Label
        KiSlider                      matlab.ui.control.Slider
        KiLabel                       matlab.ui.control.Label
        KdSliderLabel                 matlab.ui.control.Label
        KdSlider                      matlab.ui.control.Slider
        KdLabel                       matlab.ui.control.Label
        ThetaTargetEditFieldLabel     matlab.ui.control.Label
        ThetaTargetEditField          matlab.ui.control.EditField
        ThetaTargetLabel              matlab.ui.control.Label
        GenerateNewStepButton         matlab.ui.control.Button
        CurrentComPortLabel           matlab.ui.control.Label
        MessageLabel                  matlab.ui.control.Label
        DegreesLabel                  matlab.ui.control.Label
        EndStepButton                 matlab.ui.control.StateButton
        UIAxes                        matlab.ui.control.UIAxes
        ZeroPositionButton            matlab.ui.control.Button
        zero_lamp                     matlab.ui.control.Lamp
        end_step_lamp                 matlab.ui.control.Lamp
        step_lamp                     matlab.ui.control.Lamp
        UsingMacCheckBox              matlab.ui.control.CheckBox
        EnterCommPortNumberEditFieldLabel  matlab.ui.control.Label
        EnterCommPortNumberEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        % PC or Mac Choice
        using_mac = 0;
        % Serial object variables
        serial_object = 0; 
        % Controller variables
        theta_target = 0;
        kp = 0;
        ki = 0;
        kd = 0;
        % Adjust string length helper function variable
        correct_string_length = 4;
        % Expected string length for incoming sensor data
        sensordata_string_length = 7; %format: (+/-)xxx.xx
        % Data logging variables
        incoming_data_timer;
        data_index = 1;
        data_vec;
        time_vec;
        curr_time = 0; %in milliseconds
    end
    
    methods (Access = private)
        
        % Corrects the length of outgoing strings from the slider bars
        function string_out = adjust_string_length(app, string_in)
            len_string = strlength(string_in);
            if(len_string >= app.correct_string_length)
                string_out = string_in(1:app.correct_string_length);
            elseif (len_string == 1)
                string_out = string_in + ".00";
            else
                string_out = string_in + "0";
            end
        end
        
        % Read data and log
        function incoming_data_timer_fcn(app, ~, event)
            % read sensor data from Teensy
            data_raw = read(app.serial_object, app.sensordata_string_length, "string");
            data = str2double(data_raw);
            % build time and position vectors for displaying data
            app.data_vec(app.data_index) = data;
            app.time_vec(app.data_index) = app.curr_time;
            app.data_index = app.data_index + 1;
            app.curr_time = app.curr_time + 10; 
            % Display gyro angle in real time
            app.DegreesLabel.Text = "Degrees: " + string(data);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         % Create timer object
            app.incoming_data_timer = timer(...
            'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
            'Period', .01, ...                       % Period is 10 milliseconds
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.incoming_data_timer_fcn);   % Callback that runs every period
        end

        % Value changed function: KpSlider
        function KpSliderValueChanged(app, event)
            app.kp = app.KpSlider.Value;  
            app.KpLabel.Text = string(app.kp);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.incoming_data_timer);
            delete(app.serial_object);
            delete(app);
        end

        % Value changed function: KiSlider
        function KiSliderValueChanged(app, event)
            app.ki = app.KiSlider.Value;
            app.KiLabel.Text = string(app.ki);
        end

        % Value changed function: KdSlider
        function KdSliderValueChanged(app, event)
            app.kd = app.KdSlider.Value;
            app.KdLabel.Text = string(app.kd);
        end

        % Value changed function: ThetaTargetEditField
        function ThetaTargetEditFieldValueChanged(app, event)
            app.theta_target = app.ThetaTargetEditField.Value;
            app.ThetaTargetLabel.Text = "Theta Target: " + string(app.theta_target) + " degrees";
        end

        % Button pushed function: GenerateNewStepButton
        function GenerateNewStepButtonPushed(app, event)
            % Change lamp colors to indicate button press
            app.step_lamp.Color = 'green';
            app.end_step_lamp.Color = [.5 .5 .5];
            app.zero_lamp.Color = [.5 .5 .5];
            % convert numeric values to strings        
            raw_str_kp = char(string(app.kp));
            raw_str_ki = char(string(app.ki));
            raw_str_kd = char(string(app.kd));
            
            str_target = string(app.theta_target); 
            str_kp = adjust_string_length(app, raw_str_kp);
            str_ki = adjust_string_length(app, raw_str_ki);
            str_kd = adjust_string_length(app, raw_str_kd);
            
            % build message out of string
            message_out_string = str_target + " " + str_kp + " " + str_ki + " " + str_kd;
            app.MessageLabel.Text = message_out_string;
            % send message to teensy
            writeline(app.serial_object, message_out_string);
            
            %reset data log variables
            app.time_vec = [];
            app.data_vec = [];
            app.curr_time = 0;   
            app.data_index = 1; %reset to 1 for data collection (arrays index by 1 in matlab) 
            

        end

        % Value changed function: EnterCommPortNumberEditField
        function EnterCommPortNumberEditFieldValueChanged(app, event)
            if(app.using_mac)
                comm_port_str = app.EnterCommPortNumberEditField.Value;
            else
                comm_port = app.EnterCommPortNumberEditField.Value;
                comm_port_str = "COM"+comm_port;
            end
            
            try
                app.serial_object = serialport(comm_port_str, 9600);
                app.CurrentComPortLabel.Text = comm_port_str; 
                configureTerminator(app.serial_object, 0); %null terminate all strings sent and received
                start(app.incoming_data_timer);
            catch
                warning("Serial Port not valid")
            end
        end

        % Value changed function: EndStepButton
        function EndStepButtonValueChanged(app, event)
         % Change lamp color to indicate button press
         app.end_step_lamp.Color = 'red';
         app.step_lamp.Color = [.5 .5 .5];
         app.zero_lamp.Color = [.5 .5 .5];
         % Create string to end step response
         end_step_string = '!';
         % send message to teensy
         writeline(app.serial_object, end_step_string); 
         % plot step response
         vec_length = length(app.time_vec);
         target_vec = repelem(str2double(app.theta_target), vec_length); %create vec to draw target line
        
         % save data
         time_info = app.time_vec;
         data = app.data_vec;
         save("response_data.mat", "time_info", "data", "target_vec");
         
         plot(app.UIAxes, app.time_vec, app.data_vec, 'b');
         hold(app.UIAxes, "on");
         plot(app.UIAxes, app.time_vec, target_vec, 'r--');
         hold(app.UIAxes, "off");
        
        end

        % Button pushed function: ZeroPositionButton
        function ZeroPositionButtonPushed(app, event)
             % Change lamp color to indicate button press
             app.zero_lamp.Color = 'blue';
             app.step_lamp.Color = [.5 .5 .5];
             app.end_step_lamp.Color = [.5 .5 .5];
             % Create string to end step response
             zero_position_string = 'z';
             % send message to teensy
             writeline(app.serial_object, zero_position_string); 
        end

        % Value changed function: UsingMacCheckBox
        function UsingMacCheckBoxValueChanged(app, event)
            app.using_mac = app.UsingMacCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.KpSliderLabel = uilabel(app.UIFigure);
            app.KpSliderLabel.Text = 'Kp';
            app.KpSlider = uislider(app.UIFigure);
            app.KpSlider.Limits = [0 5];
            app.KpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KpLabel = uilabel(app.UIFigure);
            app.KpLabel.Text = 'Kp: ';
            app.KiSliderLabel = uilabel(app.UIFigure);
            app.KiSliderLabel.Text = 'Ki';
            app.KiSlider = uislider(app.UIFigure);
            app.KiSlider.Limits = [0 1];
            app.KiSlider.MajorTicks = [0 1 2 3 4 5];
            app.KiSlider.ValueChangedFcn = createCallbackFcn(app, @KiSliderValueChanged, true);
            app.KiLabel = uilabel(app.UIFigure);
            app.KiLabel.Text = 'Ki: ';
            app.KdSliderLabel = uilabel(app.UIFigure);
            app.KdSliderLabel.Text = 'Kd';
            app.KdSlider = uislider(app.UIFigure);
            app.KdSlider.Limits = [0 2];
            app.KdSlider.ValueChangedFcn = createCallbackFcn(app, @KdSliderValueChanged, true);
            app.KdLabel = uilabel(app.UIFigure);
            app.KdLabel.Text = 'Kd: ';
            app.ThetaTargetEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaTargetEditFieldLabel.Text = 'Theta Target';
            app.ThetaTargetEditField = uieditfield(app.UIFigure, 'text');
            app.ThetaTargetEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaTargetEditFieldValueChanged, true);
            app.ThetaTargetLabel = uilabel(app.UIFigure);
            app.ThetaTargetLabel.Text = 'Theta Target:';
            app.GenerateNewStepButton = uibutton(app.UIFigure, 'push');
            app.GenerateNewStepButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNewStepButtonPushed, true);
            app.GenerateNewStepButton.Text = 'Generate New Step';
            app.CurrentComPortLabel = uilabel(app.UIFigure);
            app.CurrentComPortLabel.Text = 'Current Com Port: ';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Message';
            app.DegreesLabel = uilabel(app.UIFigure);
            app.DegreesLabel.Text = 'Degrees: ';
            app.EndStepButton = uibutton(app.UIFigure, 'state');
            app.EndStepButton.ValueChangedFcn = createCallbackFcn(app, @EndStepButtonValueChanged, true);
            app.EndStepButton.Text = 'End Step';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Position Over Time')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Angle (Degrees)')
            app.ZeroPositionButton = uibutton(app.UIFigure, 'push');
            app.ZeroPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroPositionButtonPushed, true);
            app.ZeroPositionButton.Text = 'Zero Position';
            app.zero_lamp = uilamp(app.UIFigure);
            app.end_step_lamp = uilamp(app.UIFigure);
            app.step_lamp = uilamp(app.UIFigure);
            app.UsingMacCheckBox = uicheckbox(app.UIFigure);
            app.UsingMacCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsingMacCheckBoxValueChanged, true);
            app.UsingMacCheckBox.Text = 'Using Mac?';
            app.EnterCommPortNumberEditFieldLabel = uilabel(app.UIFigure);
            app.EnterCommPortNumberEditFieldLabel.Text = 'Enter Comm Port Number:';
            app.EnterCommPortNumberEditField = uieditfield(app.UIFigure, 'text');
            app.EnterCommPortNumberEditField.ValueChangedFcn = createCallbackFcn(app, @EnterCommPortNumberEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labKitControlStation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end